classdef MIT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        DadosdaplacaTab                matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        PotnciadesadaKWEditFieldLabel  matlab.ui.control.Label
        placaPotenciaSaida             matlab.ui.control.NumericEditField
        TensoVLabel                    matlab.ui.control.Label
        placaTensaoDelta               matlab.ui.control.NumericEditField
        FrequnciaHzEditFieldLabel      matlab.ui.control.Label
        placaFrequencia                matlab.ui.control.NumericEditField
        FatordepotnciaEditFieldLabel   matlab.ui.control.Label
        placaFatorPotencia             matlab.ui.control.NumericEditField
        NumerodeparesdepolosLabel      matlab.ui.control.Label
        placaNumParPolos               matlab.ui.control.NumericEditField
        DadosdeensaioTab_2             matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        ResistnciaEditFieldLabel       matlab.ui.control.Label
        ensaioResistencia              matlab.ui.control.NumericEditField
        TensoderotorlivreVEditFieldLabel  matlab.ui.control.Label
        ensaioTensaoRotorLivre         matlab.ui.control.NumericEditField
        CorrentederotorlivreAEditFieldLabel  matlab.ui.control.Label
        ensaioCorrenteRotorLivre       matlab.ui.control.NumericEditField
        PotnciaderotorlivreWEditFieldLabel  matlab.ui.control.Label
        ensaioPotenciaRotorLivre       matlab.ui.control.NumericEditField
        PotnciadeatritoeventilaoWEditFieldLabel  matlab.ui.control.Label
        ensaioPotenciaAtritoVentilcao  matlab.ui.control.NumericEditField
        TensoderotortravadoVEditFieldLabel  matlab.ui.control.Label
        ensaioTensaoRotorTravado       matlab.ui.control.NumericEditField
        PotnciaderotortravadoWEditFieldLabel  matlab.ui.control.Label
        ensaioPotenciaRotorTravado     matlab.ui.control.NumericEditField
        CorrentederotortravadoALabel   matlab.ui.control.Label
        ensaioCorrenteRotorTravado     matlab.ui.control.NumericEditField
        ResultadoPanel                 matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        LabelR1                        matlab.ui.control.Label
        LabelR1Resposta                matlab.ui.control.Label
        LabelJx1Resposta               matlab.ui.control.Label
        LabelJx1                       matlab.ui.control.Label
        LabelR2                        matlab.ui.control.Label
        LabelR2Resposta                matlab.ui.control.Label
        LabelJx2                       matlab.ui.control.Label
        LabelJx2Resposta               matlab.ui.control.Label
        LabelRm                        matlab.ui.control.Label
        LabelRmResposta                matlab.ui.control.Label
        LabelJxm                       matlab.ui.control.Label
        LabelJxmResposta               matlab.ui.control.Label
        LabelTitulo                    matlab.ui.control.Label
        ButtonCalcular                 matlab.ui.control.StateButton
        CheckBoxGerarGrafico           matlab.ui.control.CheckBox
        CheckBoxSalvar                 matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Latex config
            app.LabelR1.Interpreter  = 'latex'
            app.LabelJx1.Interpreter  = 'latex'
            app.LabelR2.Interpreter  = 'latex'
            app.LabelJx2.Interpreter  = 'latex'
            app.LabelRm.Interpreter  = 'latex'
            app.LabelJxm.Interpreter  = 'latex'
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Value changed function: ButtonCalcular
        function ButtonCalcularValueChanged(app, event)
            
            value = app.ButtonCalcular.Value;
            [r1, jx1, r2, jx2, rm, jxm] =  mit( ...
                app.ensaioResistencia.Value, ...
                app.placaTensaoDelta.Value, ...
                app.placaFrequencia.Value, ...
                app.placaNumParPolos.Value, ...
                app.ensaioTensaoRotorTravado.Value, ...
                app.ensaioCorrenteRotorTravado.Value, ...
                app.ensaioPotenciaRotorTravado.Value, ...
                app.ensaioTensaoRotorLivre.Value, ...
                app.ensaioCorrenteRotorLivre.Value, ...
                app.ensaioPotenciaRotorLivre.Value, ...
                app.ensaioPotenciaAtritoVentilcao.Value, ...
                app.placaPotenciaSaida.Value, ...
                app.placaFatorPotencia.Value, ...
                app.CheckBoxGerarGrafico.Value, ...
                app.CheckBoxSalvar.Value)
            fprintf("exec: mit(%f, %f, %f, %f, %f, %f, %f, %f, %f, %f, %f, %f, %f, %b, %b)", ...
                app.ensaioResistencia.Value, ...
                app.placaTensaoDelta.Value, ...
                app.placaFrequencia.Value, ...
                app.placaNumParPolos.Value, ...
                app.ensaioTensaoRotorTravado.Value, ...
                app.ensaioCorrenteRotorTravado.Value, ...
                app.ensaioPotenciaRotorTravado.Value, ...
                app.ensaioTensaoRotorLivre.Value, ...
                app.ensaioCorrenteRotorLivre.Value, ...
                app.ensaioPotenciaRotorLivre.Value, ...
                app.ensaioPotenciaAtritoVentilcao.Value, ...
                app.placaPotenciaSaida.Value, ...
                app.placaFatorPotencia.Value, ...
                app.CheckBoxGerarGrafico.Value, ...
                app.CheckBoxSalvar.Value)
             
            %% Saida
             app.LabelR1Resposta.Text = num2str(r1);
             app.LabelJx1Resposta.Text = num2str(jx1);
             app.LabelR2Resposta.Text = num2str(r2);
             app.LabelJx2Resposta.Text = num2str(jx2);
             app.LabelRmResposta.Text = num2str(rm);
             app.LabelJxmResposta.Text = num2str(jxm);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DadosdaplacaTab = uitab(app.TabGroup);
            app.DadosdaplacaTab.Title = 'Dados da placa';
            app.GridLayout = uigridlayout(app.DadosdaplacaTab);
            app.PotnciadesadaKWEditFieldLabel = uilabel(app.GridLayout);
            app.PotnciadesadaKWEditFieldLabel.Text = 'Potência de saída [KW]';
            app.placaPotenciaSaida = uieditfield(app.GridLayout, 'numeric');
            app.TensoVLabel = uilabel(app.GridLayout);
            app.TensoVLabel.Text = 'Tensão Δ [V]';
            app.placaTensaoDelta = uieditfield(app.GridLayout, 'numeric');
            app.FrequnciaHzEditFieldLabel = uilabel(app.GridLayout);
            app.FrequnciaHzEditFieldLabel.Text = 'Frequência [Hz]';
            app.placaFrequencia = uieditfield(app.GridLayout, 'numeric');
            app.FatordepotnciaEditFieldLabel = uilabel(app.GridLayout);
            app.FatordepotnciaEditFieldLabel.Text = 'Fator de potência';
            app.placaFatorPotencia = uieditfield(app.GridLayout, 'numeric');
            app.NumerodeparesdepolosLabel = uilabel(app.GridLayout);
            app.NumerodeparesdepolosLabel.Text = 'Numero de pares de polos';
            app.placaNumParPolos = uieditfield(app.GridLayout, 'numeric');
            app.DadosdeensaioTab_2 = uitab(app.TabGroup);
            app.DadosdeensaioTab_2.Title = 'Dados de ensaio';
            app.GridLayout2 = uigridlayout(app.DadosdeensaioTab_2);
            app.ResistnciaEditFieldLabel = uilabel(app.GridLayout2);
            app.ResistnciaEditFieldLabel.Text = 'Resistência [Ω]';
            app.ensaioResistencia = uieditfield(app.GridLayout2, 'numeric');
            app.TensoderotorlivreVEditFieldLabel = uilabel(app.GridLayout2);
            app.TensoderotorlivreVEditFieldLabel.Text = 'Tensão de rotor livre [V]';
            app.ensaioTensaoRotorLivre = uieditfield(app.GridLayout2, 'numeric');
            app.CorrentederotorlivreAEditFieldLabel = uilabel(app.GridLayout2);
            app.CorrentederotorlivreAEditFieldLabel.Text = 'Corrente de rotor livre [A]';
            app.ensaioCorrenteRotorLivre = uieditfield(app.GridLayout2, 'numeric');
            app.PotnciaderotorlivreWEditFieldLabel = uilabel(app.GridLayout2);
            app.PotnciaderotorlivreWEditFieldLabel.Text = 'Potência de rotor livre [W]';
            app.ensaioPotenciaRotorLivre = uieditfield(app.GridLayout2, 'numeric');
            app.PotnciadeatritoeventilaoWEditFieldLabel = uilabel(app.GridLayout2);
            app.PotnciadeatritoeventilaoWEditFieldLabel.Text = 'Potência de atrito e ventilação [W]';
            app.ensaioPotenciaAtritoVentilcao = uieditfield(app.GridLayout2, 'numeric');
            app.TensoderotortravadoVEditFieldLabel = uilabel(app.GridLayout2);
            app.TensoderotortravadoVEditFieldLabel.Text = 'Tensão de rotor travado [V]';
            app.ensaioTensaoRotorTravado = uieditfield(app.GridLayout2, 'numeric');
            app.PotnciaderotortravadoWEditFieldLabel = uilabel(app.GridLayout2);
            app.PotnciaderotortravadoWEditFieldLabel.Text = 'Potência de rotor travado [W]';
            app.ensaioPotenciaRotorTravado = uieditfield(app.GridLayout2, 'numeric');
            app.CorrentederotortravadoALabel = uilabel(app.GridLayout2);
            app.CorrentederotortravadoALabel.Text = 'Corrente de rotor travado [A]';
            app.ensaioCorrenteRotorTravado = uieditfield(app.GridLayout2, 'numeric');
            app.ResultadoPanel = uipanel(app.UIFigure);
            app.ResultadoPanel.Title = 'Resultado';
            app.GridLayout3 = uigridlayout(app.ResultadoPanel);
            app.LabelR1 = uilabel(app.GridLayout3);
            app.LabelR1.Text = '$r_{1}$';
            app.LabelR1Resposta = uilabel(app.GridLayout3);
            app.LabelR1Resposta.Text = '---';
            app.LabelJx1Resposta = uilabel(app.GridLayout3);
            app.LabelJx1Resposta.Text = '---';
            app.LabelJx1 = uilabel(app.GridLayout3);
            app.LabelJx1.Text = '$jx_{1}$';
            app.LabelR2 = uilabel(app.GridLayout3);
            app.LabelR2.Text = '$r''_{2}$';
            app.LabelR2Resposta = uilabel(app.GridLayout3);
            app.LabelR2Resposta.Text = '---';
            app.LabelJx2 = uilabel(app.GridLayout3);
            app.LabelJx2.Text = '$jx''_{2}$';
            app.LabelJx2Resposta = uilabel(app.GridLayout3);
            app.LabelJx2Resposta.Text = '---';
            app.LabelRm = uilabel(app.GridLayout3);
            app.LabelRm.Text = '$R''_{m}$';
            app.LabelRmResposta = uilabel(app.GridLayout3);
            app.LabelRmResposta.Text = '---';
            app.LabelJxm = uilabel(app.GridLayout3);
            app.LabelJxm.Text = '$jX''_{m}$';
            app.LabelJxmResposta = uilabel(app.GridLayout3);
            app.LabelJxmResposta.Text = '---';
            app.LabelTitulo = uilabel(app.UIFigure);
            app.LabelTitulo.Text = {'MIT calc 2000'; ''};
            app.ButtonCalcular = uibutton(app.UIFigure, 'state');
            app.ButtonCalcular.ValueChangedFcn = createCallbackFcn(app, @ButtonCalcularValueChanged, true);
            app.ButtonCalcular.Text = 'Calcular';
            app.CheckBoxGerarGrafico = uicheckbox(app.UIFigure);
            app.CheckBoxGerarGrafico.Text = 'Gerar gráfico';
            app.CheckBoxSalvar = uicheckbox(app.UIFigure);
            app.CheckBoxSalvar.Text = 'Salvar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MIT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end