classdef transformador_calc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AjudaButton                  matlab.ui.control.Button
        CheckBoxGraficos             matlab.ui.control.CheckBox
        EnsaiodeTranformadoresLabel  matlab.ui.control.Label
        TypeButtonGroup              matlab.ui.container.ButtonGroup
        MonofsicoButton              matlab.ui.control.RadioButton
        TrifsicoButton               matlab.ui.control.RadioButton
        CalcularButton               matlab.ui.control.Button
        SNEditField                  matlab.ui.control.NumericEditField
        S_NKVALabel                  matlab.ui.control.Label
        VNATEditField                matlab.ui.control.NumericEditField
        V_NATKVLabel                 matlab.ui.control.Label
        VNBTEditField                matlab.ui.control.NumericEditField
        V_NBTVLabel                  matlab.ui.control.Label
        V0EditField                  matlab.ui.control.NumericEditField
        V_0VLabel                    matlab.ui.control.Label
        I0EditField                  matlab.ui.control.NumericEditField
        I_0ALabel                    matlab.ui.control.Label
        P0EditField                  matlab.ui.control.NumericEditField
        P_0WLabel                    matlab.ui.control.Label
        VccEditField                 matlab.ui.control.NumericEditField
        V_CCVLabel                   matlab.ui.control.Label
        IccEditField                 matlab.ui.control.NumericEditField
        I_ccALabel                   matlab.ui.control.Label
        PCCEditField                 matlab.ui.control.NumericEditField
        P_CCWLabel                   matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        FigurasTab                   matlab.ui.container.Tab
        Image                        matlab.ui.control.Image
        Image2                       matlab.ui.control.Image
        Image3                       matlab.ui.control.Image
        Circuitosimplificado2Label   matlab.ui.control.Label
        CircuitocompletoLabel        matlab.ui.control.Label
        CircuitoSimplificado2Label   matlab.ui.control.Label
        OhmicaTab                    matlab.ui.container.Tab
        GridLayout3                  matlab.ui.container.GridLayout
        Figura1Label                 matlab.ui.control.Label
        OhmFig1_RMA                  matlab.ui.control.Label
        OhmFig1_XMA                  matlab.ui.control.Label
        OhmFig1_R2A                  matlab.ui.control.Label
        OhmFig1_X2A                  matlab.ui.control.Label
        OhmFig1_R1                   matlab.ui.control.Label
        OhmFig1_X1                   matlab.ui.control.Label
        OhmFig1_R1B                  matlab.ui.control.Label
        OhmFig1_X1B                  matlab.ui.control.Label
        OhmFig1_R2                   matlab.ui.control.Label
        OhmFig1_X2                   matlab.ui.control.Label
        OhmFig1_RM                   matlab.ui.control.Label
        OhmFig1_XM                   matlab.ui.control.Label
        OhmicaTab_2                  matlab.ui.container.Tab
        GridLayout4                  matlab.ui.container.GridLayout
        Figura2Label                 matlab.ui.control.Label
        OhmFig2_RMA                  matlab.ui.control.Label
        OhmFig2_XMA                  matlab.ui.control.Label
        OhmFig2_R1TS                 matlab.ui.control.Label
        OhmFig2_RM                   matlab.ui.control.Label
        OhmFig2_XM                   matlab.ui.control.Label
        OhmFig2_R1SB                 matlab.ui.control.Label
        Figura3Label                 matlab.ui.control.Label
        OhmFig3_R1TS                 matlab.ui.control.Label
        OhmFig3_X1S                  matlab.ui.control.Label
        OhmFig3_R1SB                 matlab.ui.control.Label
        OhmFig2_X1S                  matlab.ui.control.Label
        OhmFig2_X1SB                 matlab.ui.control.Label
        OhmFig3_X1SB                 matlab.ui.control.Label
        PUTab                        matlab.ui.container.Tab
        GridLayout5                  matlab.ui.container.GridLayout
        Figura1Label_2               matlab.ui.control.Label
        PuFig1_RMPU                  matlab.ui.control.Label
        PuFig1_XMPU                  matlab.ui.control.Label
        PuFig1_R1PU                  matlab.ui.control.Label
        PuFig1_R2APU                 matlab.ui.control.Label
        PuFig1_X2APU                 matlab.ui.control.Label
        PuFig1_X1PU                  matlab.ui.control.Label
        PUTab_2                      matlab.ui.container.Tab
        GridLayout7                  matlab.ui.container.GridLayout
        Figura2Label_2               matlab.ui.control.Label
        PuFig2_R1STPU                matlab.ui.control.Label
        PuFig2_X1SPU                 matlab.ui.control.Label
        PuFig2_RMPU                  matlab.ui.control.Label
        PuFig2_XMPU                  matlab.ui.control.Label
        Figura3Label_2               matlab.ui.control.Label
        PuFig3_R1STPU                matlab.ui.control.Label
        PuFig3_X1SPU                 matlab.ui.control.Label
        ComparaoTab                  matlab.ui.container.Tab
        GridLayout6                  matlab.ui.container.GridLayout
        Comp_01                      matlab.ui.control.Label
        Comp_02                      matlab.ui.control.Label
        Comp_03                      matlab.ui.control.Label
        Comp_04                      matlab.ui.control.Label
        Comp_05                      matlab.ui.control.Label
        Comp_06                      matlab.ui.control.Label
        Comp_07                      matlab.ui.control.Label
        Comp_08                      matlab.ui.control.Label
        ComparaoOhmicoporPULabel     matlab.ui.control.Label
        RendimentoTab                matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        Rend080                      matlab.ui.control.Label
        Comp080                      matlab.ui.control.Label
        Rend085                      matlab.ui.control.Label
        Comp085                      matlab.ui.control.Label
        Rend090                      matlab.ui.control.Label
        Comp090                      matlab.ui.control.Label
        Rend095                      matlab.ui.control.Label
        Comp095                      matlab.ui.control.Label
        Comp100                      matlab.ui.control.Label
        Rend100                      matlab.ui.control.Label
        Carregamento                 matlab.ui.control.Label
        SalvarCheckBox               matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("./Imagens/")
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%INPUT%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.S_NKVALabel.Interpreter  = 'latex'
            app.V_NATKVLabel.Interpreter  = 'latex'
            app.V_NBTVLabel.Interpreter  = 'latex'
            app.V_0VLabel.Interpreter  = 'latex'
            app.I_0ALabel.Interpreter  = 'latex'
            app.P_0WLabel.Interpreter  = 'latex'
            app.V_CCVLabel.Interpreter  = 'latex'
            app.I_ccALabel.Interpreter  = 'latex'
            app.P_CCWLabel.Interpreter  = 'latex'
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%OUTPUT%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Ohm fig 1
            app.OhmFig1_RMA.Interpreter  = 'latex'
            app.OhmFig1_XMA.Interpreter  = 'latex'
            app.OhmFig1_R2A.Interpreter  = 'latex'
            app.OhmFig1_X2A.Interpreter  = 'latex'
            app.OhmFig1_R1.Interpreter  = 'latex'
            app.OhmFig1_X1.Interpreter  = 'latex'
            app.OhmFig1_R1B.Interpreter  = 'latex'
            app.OhmFig1_X1B.Interpreter  = 'latex'
            app.OhmFig1_R2.Interpreter  = 'latex'
            app.OhmFig1_X2.Interpreter  = 'latex'
            app.OhmFig1_RM.Interpreter  = 'latex'
            app.OhmFig1_XM.Interpreter  = 'latex'
            
            % Ohm fig 2
            app.OhmFig2_RMA.Interpreter  = 'latex'
            app.OhmFig2_XMA.Interpreter  = 'latex'
            app.OhmFig2_R1TS.Interpreter  = 'latex'
            app.OhmFig2_X1S.Interpreter  = 'latex'
            app.OhmFig2_RM.Interpreter  = 'latex'
            app.OhmFig2_XM.Interpreter  = 'latex'
            app.OhmFig2_R1SB.Interpreter  = 'latex'
            app.OhmFig2_X1SB.Interpreter  = 'latex'
            
            % Ohm fig 3
            app.OhmFig3_R1TS.Interpreter  = 'latex'
            app.OhmFig3_X1S.Interpreter  = 'latex'
            app.OhmFig3_R1SB.Interpreter  = 'latex'
            app.OhmFig3_X1SB.Interpreter  = 'latex'
            
            % PU fig 1
            app.PuFig1_RMPU.Interpreter  = 'latex'
            app.PuFig1_XMPU.Interpreter  = 'latex'
            app.PuFig1_R2APU.Interpreter  = 'latex'
            app.PuFig1_X2APU.Interpreter  = 'latex'
            app.PuFig1_R1PU.Interpreter  = 'latex'
            app.PuFig1_X1PU.Interpreter  = 'latex'
            
            % PU fig 2 
            app.PuFig2_R1STPU.Interpreter  = 'latex'
            app.PuFig2_X1SPU.Interpreter  = 'latex'
            app.PuFig2_RMPU.Interpreter  = 'latex'
            app.PuFig2_XMPU.Interpreter  = 'latex'
            
            % PU fig 3
            app.PuFig3_R1STPU.Interpreter  = 'latex'
            app.PuFig3_X1SPU.Interpreter  = 'latex'
            
            % Comparação Ohm x PU
            app.Comp_01.Interpreter  = 'latex'
            app.Comp_02.Interpreter  = 'latex'
            app.Comp_03.Interpreter  = 'latex'
            app.Comp_04.Interpreter  = 'latex'
            app.Comp_05.Interpreter  = 'latex'
            app.Comp_06.Interpreter  = 'latex'
            app.Comp_07.Interpreter  = 'latex'
            app.Comp_08.Interpreter  = 'latex'
            % Rendimento Latex
            app.Carregamento.Interpreter  = 'latex'
            app.Rend080.Interpreter  = 'latex'
            app.Rend085.Interpreter  = 'latex'
            app.Rend090.Interpreter  = 'latex'
            app.Rend095.Interpreter  = 'latex'
            app.Rend100.Interpreter  = 'latex'
            app.Comp080.Interpreter  = 'latex'
            app.Comp085.Interpreter  = 'latex'
            app.Comp090.Interpreter  = 'latex'
            app.Comp095.Interpreter  = 'latex'
            app.Comp100.Interpreter  = 'latex'
        end

        % Button pushed function: AjudaButton
        function AjudaButtonPushed(app, event)
            web("https://github.com/emanuel-alves/Trabalho_EELI16")
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            close all;
            switch app.TypeButtonGroup.SelectedObject.Text
                case 'Monofásico'
                    
                    [OhmFig1, OhmFig2, OhmFig3, PuFig1, PuFig2, PuFig3, CompOhmPu, IMAX, RendMPC, CompRend] = tranformador_monofasico( ...
                        app.SNEditField.Value, ...
                        app.VNATEditField.Value, ...
                        app.VNBTEditField.Value, ...
                        app.V0EditField.Value, ...
                        app.I0EditField.Value, ...
                        app.P0EditField.Value, ...
                        app.VccEditField.Value, ...
                        app.IccEditField.Value, ...
                        app.PCCEditField.Value, ...
                        app.CheckBoxGraficos.Value, ...
                        app.SalvarCheckBox.Value)
                case 'Trifásico'
                    [OhmFig1, OhmFig2, OhmFig3, PuFig1, PuFig2, PuFig3, CompOhmPu, IMAX, RendMPC, CompRend] = tranformador_trifasico( ...
                        app.SNEditField.Value, ...
                        app.VNATEditField.Value, ...
                        app.VNBTEditField.Value, ...
                        app.V0EditField.Value, ...
                        app.I0EditField.Value, ...
                        app.P0EditField.Value, ...
                        app.VccEditField.Value, ...
                        app.IccEditField.Value, ...
                        app.PCCEditField.Value, ...
                        app.CheckBoxGraficos.Value, ...
                        app.SalvarCheckBox.Value, ...
                        dir)
            end
            
            %%%%%%%%%%%%%%%%%%OUTPUT%%%%%%%%%%%%%%%%%%
            % Figura 1
            %app.EnsaiodeTranformadoresLabel.Text = numstr(fig1(1));
            
            
            % Rendimento
            app.Carregamento.Text = "$I_{2pu}$ = " + num2str(IMAX);
            app.Rend080.Text = "$\eta_{0.80\%}$ = " + num2str(RendMPC(1));
            app.Rend085.Text = "$\eta_{0.85\%}$ = " + num2str(RendMPC(2));
            app.Rend090.Text = "$\eta_{0.90\%}$ = " + num2str(RendMPC(3));
            app.Rend095.Text = "$\eta_{0.95\%}$ = " + num2str(RendMPC(4));
            app.Rend100.Text = "$\eta_{1.00\%}$ = " + num2str(RendMPC(5));
            
            app.Comp080.Text = "$Comp_{0.80\%}$ = " + num2str(CompRend(1));
            app.Comp085.Text = "$Comp_{0.85\%}$ = " + num2str(CompRend(2));
            app.Comp090.Text = "$Comp_{0.90\%}$ = " + num2str(CompRend(3));
            app.Comp095.Text = "$Comp_{0.95\%}$ = " + num2str(CompRend(4));
            app.Comp100.Text = "$Comp_{1.00\%}$ = " + num2str(CompRend(5));
            
            % Calculo Ohm fig1
            app.OhmFig1_RMA.Text    = "$R_{MA}$ = " + num2str(OhmFig1(1));
            app.OhmFig1_XMA.Text    = "$X_{MA}$ = " + num2str(OhmFig1(2));
            app.OhmFig1_R2A.Text    = "$R_{2A}$ = " + num2str(OhmFig1(3));
            app.OhmFig1_X2A.Text    = "$X_{2A}$ = " + num2str(OhmFig1(4));
            app.OhmFig1_R1.Text     = "$R_{1}$ = " + num2str(OhmFig1(5));
            app.OhmFig1_X1.Text     = "$X_{1}$ = " + num2str(OhmFig1(6));
            app.OhmFig1_R1B.Text    = "$R_{1B}$ = " + num2str(OhmFig1(7));
            app.OhmFig1_X1B.Text    = "$X_{1B}$ = " + num2str(OhmFig1(8));
            app.OhmFig1_R2.Text     = "$R_{2}$ = " + num2str(OhmFig1(9));
            app.OhmFig1_X2.Text     = "$X_{2}$ = " + num2str(OhmFig1(10));
            app.OhmFig1_RM.Text     = "$R_{M}$ = " + num2str(OhmFig1(11));
            app.OhmFig1_XM.Text     = "$X_{M}$ = " + num2str(OhmFig1(12));
            
            % Calculo Ohm fig2
            app.OhmFig2_RMA.Text    = "$R_{MA}$ = " + num2str(OhmFig2(1));
            app.OhmFig2_XMA.Text    = "$X_{MA}$ = " + num2str(OhmFig2(2));
            app.OhmFig2_R1TS.Text   = "$R_{1TS}$ = " + num2str(OhmFig2(3));
            app.OhmFig2_X1S.Text    = "$X_{1S}$ = " + num2str(OhmFig2(4));
            app.OhmFig2_RM.Text     = "$R_{M}$ = " + num2str(OhmFig2(5));
            app.OhmFig2_XM.Text     = "$X_{M}$ = " + num2str(OhmFig2(6));
            app.OhmFig2_R1SB.Text   = "$R_{1SB}$ = " + num2str(OhmFig2(7));
            app.OhmFig2_X1SB.Text   = "$X_{1SB}$ = " + num2str(OhmFig2(8));
            % Calculo Ohm fig3
            app.OhmFig3_R1TS.Text   = "$R_{1TS}$ = " + num2str(OhmFig3(1));
            app.OhmFig3_X1S.Text    = "$X_{1S}$ = " + num2str(OhmFig3(2));
            app.OhmFig3_R1SB.Text   = "$R_{1SB}$ = " + num2str(OhmFig3(3));
            app.OhmFig3_X1SB.Text   = "$X_{1SB}$ = " + num2str(OhmFig3(4));
            
            % Calculo PU fig1
            app.PuFig1_RMPU.Text    = "$R_{MPU}$ = " + num2str(PuFig1(1));
            app.PuFig1_XMPU.Text    = "$X_{MPU}$ = " + num2str(PuFig1(2));
            app.PuFig1_R2APU.Text   = "$R_{2APU}$ = " + num2str(PuFig1(3));
            app.PuFig1_X2APU.Text   = "$X_{2APU}$ = " + num2str(PuFig1(4));
            app.PuFig1_R1PU.Text    = "$R_{1PU}$ = " + num2str(PuFig1(5));
            app.PuFig1_X1PU.Text    = "$X_{1PU}$ = " + num2str(PuFig1(6));
            
            % Calculo PU fig2
            app.PuFig2_R1STPU.Text  = "$R_{1STPU}$ = " + num2str(PuFig2(1));
            app.PuFig2_X1SPU.Text   = "$X_{1SPU}$ = " + num2str(PuFig2(2));
            app.PuFig2_RMPU.Text    = "$R_{MPU}$ = " + num2str(PuFig2(3));
            app.PuFig2_XMPU.Text    = "$X_{MPU}$ = " + num2str(PuFig2(4));
            % Calculo PU fig3
            app.PuFig3_R1STPU.Text  = "$R_{1STPU}$ = " + num2str(PuFig3(1));
            app.PuFig3_X1SPU.Text   = "$X_{1SPU}$ = " + num2str(PuFig3(2));
            % Comparação Ohm PU
            app.Comp_01.Text        = "R_{MPUB}$ = " + num2str(CompOhmPu(1));
            app.Comp_02.Text        = "R_{MPU}$ = " + num2str(CompOhmPu(2));
            app.Comp_03.Text        = "X_{MPUB}$ = " + num2str(CompOhmPu(3));
            app.Comp_04.Text        = "X_{MPU}$ = " + num2str(CompOhmPu(4));
            app.Comp_05.Text        = "R_{1TSPUB}$ = " + num2str(CompOhmPu(5));
            app.Comp_06.Text        = "R_{1STPU}$ = " + num2str(CompOhmPu(6));
            app.Comp_07.Text        = "X_{1SPUB}$ = " + num2str(CompOhmPu(7));
            app.Comp_08.Text        = "X_{1SPU}$ = " + num2str(CompOhmPu(8));


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AjudaButton = uibutton(app.UIFigure, 'push');
            app.AjudaButton.ButtonPushedFcn = createCallbackFcn(app, @AjudaButtonPushed, true);
            app.AjudaButton.Text = 'Ajuda';
            app.CheckBoxGraficos = uicheckbox(app.UIFigure);
            app.CheckBoxGraficos.Text = 'Gerar Gráficos';
            app.CheckBoxGraficos.Value = true;
            app.EnsaiodeTranformadoresLabel = uilabel(app.UIFigure);
            app.EnsaiodeTranformadoresLabel.Text = 'Ensaio de Tranformadores';
            app.TypeButtonGroup = uibuttongroup(app.UIFigure);
            app.TypeButtonGroup.Title = 'Tipo de transformador';
            app.MonofsicoButton = uiradiobutton(app.TypeButtonGroup);
            app.MonofsicoButton.Text = 'Monofásico';
            app.MonofsicoButton.Value = true;
            app.TrifsicoButton = uiradiobutton(app.TypeButtonGroup);
            app.TrifsicoButton.Text = 'Trifásico';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = {'Calcular'; ''};
            app.SNEditField = uieditfield(app.UIFigure, 'numeric');
            app.S_NKVALabel = uilabel(app.UIFigure);
            app.S_NKVALabel.Text = '$S_N \ [KVA] $';
            app.VNATEditField = uieditfield(app.UIFigure, 'numeric');
            app.V_NATKVLabel = uilabel(app.UIFigure);
            app.V_NATKVLabel.Text = '$V_{NAT} \ [KV] $';
            app.VNBTEditField = uieditfield(app.UIFigure, 'numeric');
            app.V_NBTVLabel = uilabel(app.UIFigure);
            app.V_NBTVLabel.Text = '$V_{NBT} \ [V] $';
            app.V0EditField = uieditfield(app.UIFigure, 'numeric');
            app.V_0VLabel = uilabel(app.UIFigure);
            app.V_0VLabel.Text = '$V_0 \ [V] $';
            app.I0EditField = uieditfield(app.UIFigure, 'numeric');
            app.I_0ALabel = uilabel(app.UIFigure);
            app.I_0ALabel.Text = '$I_0 \ [A] $';
            app.P0EditField = uieditfield(app.UIFigure, 'numeric');
            app.P_0WLabel = uilabel(app.UIFigure);
            app.P_0WLabel.Text = '$P_0 \ [W] $';
            app.VccEditField = uieditfield(app.UIFigure, 'numeric');
            app.V_CCVLabel = uilabel(app.UIFigure);
            app.V_CCVLabel.Text = '$V_{CC} \ [V] $';
            app.IccEditField = uieditfield(app.UIFigure, 'numeric');
            app.I_ccALabel = uilabel(app.UIFigure);
            app.I_ccALabel.Text = '$I_{cc } \ [A] $';
            app.PCCEditField = uieditfield(app.UIFigure, 'numeric');
            app.P_CCWLabel = uilabel(app.UIFigure);
            app.P_CCWLabel.Text = '$P_{CC} \ [W] $';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FigurasTab = uitab(app.TabGroup);
            app.FigurasTab.Title = 'Figuras';
            app.Image = uiimage(app.FigurasTab);
            app.Image.Tooltip = {'Modelo completo do transformador.'};
            app.Image.ImageSource = 'Completo.png';
            app.Image2 = uiimage(app.FigurasTab);
            app.Image2.Tooltip = {'Modelo simplificado 1 do transformador, onde as resistências e reatâncias de dispersão dos'; 'enrolamentos primário e secundário foram consideradas em série.'};
            app.Image2.ImageSource = 'Simplificado_1.png';
            app.Image3 = uiimage(app.FigurasTab);
            app.Image3.Tooltip = {'Modelo simplificado 2 do transformador, onde o ramo de magnetização foi desprezado'; ''; ''};
            app.Image3.ImageSource = 'Simplificado_2.png';
            app.Circuitosimplificado2Label = uilabel(app.FigurasTab);
            app.Circuitosimplificado2Label.Text = '1 -  Circuito simplificado  2';
            app.CircuitocompletoLabel = uilabel(app.FigurasTab);
            app.CircuitocompletoLabel.Text = '2 - Circuito completo';
            app.CircuitoSimplificado2Label = uilabel(app.FigurasTab);
            app.CircuitoSimplificado2Label.Text = {'3 - CircuitoSimplificado 2'; ''};
            app.OhmicaTab = uitab(app.TabGroup);
            app.OhmicaTab.Title = 'Ohmica';
            app.GridLayout3 = uigridlayout(app.OhmicaTab);
            app.Figura1Label = uilabel(app.GridLayout3);
            app.Figura1Label.Text = 'Figura 1';
            app.OhmFig1_RMA = uilabel(app.GridLayout3);
            app.OhmFig1_RMA.Text = '$R_{MA}$ = x';
            app.OhmFig1_XMA = uilabel(app.GridLayout3);
            app.OhmFig1_XMA.Text = '$X_{MA}$ = x';
            app.OhmFig1_R2A = uilabel(app.GridLayout3);
            app.OhmFig1_R2A.Text = '$R_{2A}$ = x';
            app.OhmFig1_X2A = uilabel(app.GridLayout3);
            app.OhmFig1_X2A.Text = 'X_{2A}$ = x';
            app.OhmFig1_R1 = uilabel(app.GridLayout3);
            app.OhmFig1_R1.Text = 'R_{1}$ = x';
            app.OhmFig1_X1 = uilabel(app.GridLayout3);
            app.OhmFig1_X1.Text = '$X_{1}$ = x';
            app.OhmFig1_R1B = uilabel(app.GridLayout3);
            app.OhmFig1_R1B.Text = '$R_{1B}$ = x';
            app.OhmFig1_X1B = uilabel(app.GridLayout3);
            app.OhmFig1_X1B.Text = '$X_{1B}$ = x';
            app.OhmFig1_R2 = uilabel(app.GridLayout3);
            app.OhmFig1_R2.Text = '$R_{2}$ = x';
            app.OhmFig1_X2 = uilabel(app.GridLayout3);
            app.OhmFig1_X2.Text = '$X_{2}$ = x';
            app.OhmFig1_RM = uilabel(app.GridLayout3);
            app.OhmFig1_RM.Text = '$R_{M}$ = x';
            app.OhmFig1_XM = uilabel(app.GridLayout3);
            app.OhmFig1_XM.Text = '$X_{M}$ = x';
            app.OhmicaTab_2 = uitab(app.TabGroup);
            app.OhmicaTab_2.Title = 'Ohmica';
            app.GridLayout4 = uigridlayout(app.OhmicaTab_2);
            app.Figura2Label = uilabel(app.GridLayout4);
            app.Figura2Label.Text = {'Figura 2'; ''};
            app.OhmFig2_RMA = uilabel(app.GridLayout4);
            app.OhmFig2_RMA.Text = '$R_{MA}$ = x';
            app.OhmFig2_XMA = uilabel(app.GridLayout4);
            app.OhmFig2_XMA.Text = '$X_{MA}$ = x';
            app.OhmFig2_R1TS = uilabel(app.GridLayout4);
            app.OhmFig2_R1TS.Text = '$R_{1TS}$ = x';
            app.OhmFig2_RM = uilabel(app.GridLayout4);
            app.OhmFig2_RM.Text = '$R_{M}$ = x';
            app.OhmFig2_XM = uilabel(app.GridLayout4);
            app.OhmFig2_XM.Text = '$X_{M}$ = x';
            app.OhmFig2_R1SB = uilabel(app.GridLayout4);
            app.OhmFig2_R1SB.Text = '$R_{1SB}$ = x';
            app.Figura3Label = uilabel(app.GridLayout4);
            app.Figura3Label.Text = {'Figura 3'; ''};
            app.OhmFig3_R1TS = uilabel(app.GridLayout4);
            app.OhmFig3_R1TS.Text = '$R_{1TS}$ = x';
            app.OhmFig3_X1S = uilabel(app.GridLayout4);
            app.OhmFig3_X1S.Text = '$X_{1S}$ = x';
            app.OhmFig3_R1SB = uilabel(app.GridLayout4);
            app.OhmFig3_R1SB.Text = '$R_{1SB}$ = x';
            app.OhmFig2_X1S = uilabel(app.GridLayout4);
            app.OhmFig2_X1S.Text = '$X_{1S}$ = x';
            app.OhmFig2_X1SB = uilabel(app.GridLayout4);
            app.OhmFig2_X1SB.Text = '$X_{1SB}$ = x';
            app.OhmFig3_X1SB = uilabel(app.GridLayout4);
            app.OhmFig3_X1SB.Text = '$X_{1SB}$ = x';
            app.PUTab = uitab(app.TabGroup);
            app.PUTab.Title = 'PU';
            app.GridLayout5 = uigridlayout(app.PUTab);
            app.Figura1Label_2 = uilabel(app.GridLayout5);
            app.Figura1Label_2.Text = 'Figura 1';
            app.PuFig1_RMPU = uilabel(app.GridLayout5);
            app.PuFig1_RMPU.Text = '$R_{MPU}$ = x';
            app.PuFig1_XMPU = uilabel(app.GridLayout5);
            app.PuFig1_XMPU.Text = '$X_{MPU}$ = x';
            app.PuFig1_R1PU = uilabel(app.GridLayout5);
            app.PuFig1_R1PU.Text = '$R_{1PU}$ = x';
            app.PuFig1_R2APU = uilabel(app.GridLayout5);
            app.PuFig1_R2APU.Text = '$R_{2APU}$ = x';
            app.PuFig1_X2APU = uilabel(app.GridLayout5);
            app.PuFig1_X2APU.Text = '$X_{2APU}$ = x';
            app.PuFig1_X1PU = uilabel(app.GridLayout5);
            app.PuFig1_X1PU.Text = '$X_{1PU}$ = x';
            app.PUTab_2 = uitab(app.TabGroup);
            app.PUTab_2.Title = 'PU';
            app.GridLayout7 = uigridlayout(app.PUTab_2);
            app.Figura2Label_2 = uilabel(app.GridLayout7);
            app.Figura2Label_2.Text = 'Figura 2';
            app.PuFig2_R1STPU = uilabel(app.GridLayout7);
            app.PuFig2_R1STPU.Text = '$R_{1STPU}$ = x';
            app.PuFig2_X1SPU = uilabel(app.GridLayout7);
            app.PuFig2_X1SPU.Text = '$X_{1SPU}$ = x';
            app.PuFig2_RMPU = uilabel(app.GridLayout7);
            app.PuFig2_RMPU.Text = '$R_{MPU}$ = x';
            app.PuFig2_XMPU = uilabel(app.GridLayout7);
            app.PuFig2_XMPU.Text = '$X_{MPU}$ = x';
            app.Figura3Label_2 = uilabel(app.GridLayout7);
            app.Figura3Label_2.Text = 'Figura 3';
            app.PuFig3_R1STPU = uilabel(app.GridLayout7);
            app.PuFig3_R1STPU.Text = '$R_{1STPU}$ = x';
            app.PuFig3_X1SPU = uilabel(app.GridLayout7);
            app.PuFig3_X1SPU.Text = '$X_{1SPU}$ = x';
            app.ComparaoTab = uitab(app.TabGroup);
            app.ComparaoTab.Title = 'Comparação ';
            app.GridLayout6 = uigridlayout(app.ComparaoTab);
            app.Comp_01 = uilabel(app.GridLayout6);
            app.Comp_01.Text = '$R_{MPUB}$ = x';
            app.Comp_02 = uilabel(app.GridLayout6);
            app.Comp_02.Text = '$R_{MPU}$ = x';
            app.Comp_03 = uilabel(app.GridLayout6);
            app.Comp_03.Text = '$X_{MPUB}$ = x';
            app.Comp_04 = uilabel(app.GridLayout6);
            app.Comp_04.Text = '$X_{MPU}$ = x';
            app.Comp_05 = uilabel(app.GridLayout6);
            app.Comp_05.Text = '$R_{1TSPUB}$ = x';
            app.Comp_06 = uilabel(app.GridLayout6);
            app.Comp_06.Text = '$R_{1STPU}$ = x';
            app.Comp_07 = uilabel(app.GridLayout6);
            app.Comp_07.Text = '$X_{1SPUB}$ = x';
            app.Comp_08 = uilabel(app.GridLayout6);
            app.Comp_08.Text = '$X_{1SPU}$ = x';
            app.ComparaoOhmicoporPULabel = uilabel(app.GridLayout6);
            app.ComparaoOhmicoporPULabel.Text = 'Comparação Ohmico por PU';
            app.RendimentoTab = uitab(app.TabGroup);
            app.RendimentoTab.Title = 'Rendimento';
            app.GridLayout2 = uigridlayout(app.RendimentoTab);
            app.Rend080 = uilabel(app.GridLayout2);
            app.Rend080.Text = '$\eta_{0.80\%}$ = x';
            app.Comp080 = uilabel(app.GridLayout2);
            app.Comp080.Text = '$Comp_{0.85\%}$ = x';
            app.Rend085 = uilabel(app.GridLayout2);
            app.Rend085.Text = '$\eta_{0.85\%}$ = x';
            app.Comp085 = uilabel(app.GridLayout2);
            app.Comp085.Text = '$Comp_{0.85\%}$ = x';
            app.Rend090 = uilabel(app.GridLayout2);
            app.Rend090.Text = '$\eta_{0.90\%}$ = x';
            app.Comp090 = uilabel(app.GridLayout2);
            app.Comp090.Text = '$Comp_{0.90\%}$ = x';
            app.Rend095 = uilabel(app.GridLayout2);
            app.Rend095.Text = '$\eta_{0.95\%}$ = x';
            app.Comp095 = uilabel(app.GridLayout2);
            app.Comp095.Text = '$Comp_{0.95\%}$ = x';
            app.Comp100 = uilabel(app.GridLayout2);
            app.Comp100.Text = '$Comp_{1.00\%}$ = x';
            app.Rend100 = uilabel(app.GridLayout2);
            app.Rend100.Text = '$\eta_{1.00\%}$ = x';
            app.Carregamento = uilabel(app.GridLayout2);
            app.Carregamento.Text = '$I_{2pu}$ =  x';
            app.SalvarCheckBox = uicheckbox(app.UIFigure);
            app.SalvarCheckBox.Tooltip = {''; ''};
            app.SalvarCheckBox.Text = 'Salvar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = transformador_calc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end